classdef drench < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DRENCHUIFigure   matlab.ui.Figure
        Button           matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
        Button_6         matlab.ui.control.Button
        Button_7         matlab.ui.control.Button
        Button_8         matlab.ui.control.Button
        Button_9         matlab.ui.control.Button
        Button_10        matlab.ui.control.Button
        Button_11        matlab.ui.control.Button
        Button_12        matlab.ui.control.Button
        Button_13        matlab.ui.control.Button
        Button_14        matlab.ui.control.Button
        Button_15        matlab.ui.control.Button
        Button_16        matlab.ui.control.Button
        Button_17        matlab.ui.control.Button
        Button_18        matlab.ui.control.Button
        Button_19        matlab.ui.control.Button
        Button_20        matlab.ui.control.Button
        Button_21        matlab.ui.control.Button
        Button_22        matlab.ui.control.Button
        Button_23        matlab.ui.control.Button
        Button_24        matlab.ui.control.Button
        Button_25        matlab.ui.control.Button
        Button_26        matlab.ui.control.Button
        Button_27        matlab.ui.control.Button
        Button_28        matlab.ui.control.Button
        Button_29        matlab.ui.control.Button
        Button_30        matlab.ui.control.Button
        Button_31        matlab.ui.control.Button
        Button_32        matlab.ui.control.Button
        Button_33        matlab.ui.control.Button
        Button_34        matlab.ui.control.Button
        Button_35        matlab.ui.control.Button
        Button_36        matlab.ui.control.Button
        Button_37        matlab.ui.control.Button
        Button_38        matlab.ui.control.Button
        Button_39        matlab.ui.control.Button
        Button_40        matlab.ui.control.Button
        Button_41        matlab.ui.control.Button
        Button_42        matlab.ui.control.Button
        Button_43        matlab.ui.control.Button
        Button_44        matlab.ui.control.Button
        Button_45        matlab.ui.control.Button
        Button_46        matlab.ui.control.Button
        Button_47        matlab.ui.control.Button
        Button_48        matlab.ui.control.Button
        Button_49        matlab.ui.control.Button
        Button_50        matlab.ui.control.Button
        Button_51        matlab.ui.control.Button
        Button_52        matlab.ui.control.Button
        Button_53        matlab.ui.control.Button
        Button_54        matlab.ui.control.Button
        Button_55        matlab.ui.control.Button
        Button_56        matlab.ui.control.Button
        Button_57        matlab.ui.control.Button
        Button_58        matlab.ui.control.Button
        Button_59        matlab.ui.control.Button
        Button_60        matlab.ui.control.Button
        Button_61        matlab.ui.control.Button
        Button_62        matlab.ui.control.Button
        Button_63        matlab.ui.control.Button
        Button_64        matlab.ui.control.Button
        Button_65        matlab.ui.control.Button
        Button_66        matlab.ui.control.Button
        Button_67        matlab.ui.control.Button
        Button_68        matlab.ui.control.Button
        Button_69        matlab.ui.control.Button
        Button_70        matlab.ui.control.Button
        Button_71        matlab.ui.control.Button
        Button_72        matlab.ui.control.Button
        Button_73        matlab.ui.control.Button
        Button_74        matlab.ui.control.Button
        Button_75        matlab.ui.control.Button
        Button_76        matlab.ui.control.Button
        Button_77        matlab.ui.control.Button
        Button_78        matlab.ui.control.Button
        Button_79        matlab.ui.control.Button
        Button_80        matlab.ui.control.Button
        Button_81        matlab.ui.control.Button
        Button_82        matlab.ui.control.Button
        Button_83        matlab.ui.control.Button
        Button_84        matlab.ui.control.Button
        Button_85        matlab.ui.control.Button
        Button_86        matlab.ui.control.Button
        Button_87        matlab.ui.control.Button
        Button_88        matlab.ui.control.Button
        Button_89        matlab.ui.control.Button
        Button_90        matlab.ui.control.Button
        Button_91        matlab.ui.control.Button
        Button_92        matlab.ui.control.Button
        Button_93        matlab.ui.control.Button
        Button_94        matlab.ui.control.Button
        Button_95        matlab.ui.control.Button
        Button_96        matlab.ui.control.Button
        Button_97        matlab.ui.control.Button
        Button_98        matlab.ui.control.Button
        Button_99        matlab.ui.control.Button
        Button_100       matlab.ui.control.Button
        Button_101       matlab.ui.control.Button
        Button_102       matlab.ui.control.Button
        Button_103       matlab.ui.control.Button
        Button_104       matlab.ui.control.Button
        Button_105       matlab.ui.control.Button
        Label            matlab.ui.control.Label
        SCORELabel       matlab.ui.control.Label
        PlayButton       matlab.ui.control.Button
        MUSICLabel       matlab.ui.control.Label
        RestartButton    matlab.ui.control.Button
        StopButton       matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        Label_4          matlab.ui.control.Label
        Label_5          matlab.ui.control.Label
        HowtoplayButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
       % Description
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      
      global x
      global k  


       [x,k] = drenchgame();
       Y = randi(5);
       [x,k] = drenchgame(x,Y,k);
       drenchbutton
       app.Label.Text = num2str(0);
       
       
       clear sound;
       [y,Fs] = audioread('gymno.mp3');
       sound(y,Fs);


        end

        % Button pushed function: Button_101
        function Button_101Pushed(app, event)
            global x
            global k
            Y = 1;
         [x,k] = drenchgame(x,Y,k);
         drenchbutton
         [y,Fs] = audioread('click.mp3');
                sound(y,Fs);
        end

        % Button pushed function: Button_102
        function Button_102Pushed(app, event)
               global x
               global k
               Y = 2;
            [x,k] = drenchgame(x,Y,k);
            drenchbutton
            [y,Fs] = audioread('click.mp3');
                sound(y,Fs);
        end

        % Button pushed function: Button_103
        function Button_103Pushed(app, event)
               global x
               global k
               Y = 5;
            [x,k] = drenchgame(x,Y,k);
            drenchbutton
            [y,Fs] = audioread('click.mp3');
                sound(y,Fs);
            
        end

        % Button pushed function: Button_104
        function Button_104Pushed(app, event)
               global x
               global k
               Y = 4;
            [x,k] = drenchgame(x,Y,k);
            drenchbutton
            [y,Fs] = audioread('click.mp3');
                sound(y,Fs);
        end

        % Button pushed function: Button_105
        function Button_105Pushed(app, event)
               global x
               global k
               Y = 3;
            [x,k] = drenchgame(x,Y,k);
            drenchbutton
            [y,Fs] = audioread('click.mp3');
                sound(y,Fs);
        
        end

        % Button pushed function: Button_88
        function Button_88Pushed(app, event)
            global x
            k = 0;
            Y = 1;
            x = drenchgame([ 1 1 1 1 1 1 1 1 1 1; 2 2 2 3 3 3 1 4 1 1;2 1 1 3 1 3 4 1 4 1; 2 2 2 3 3 3 4 4 4 1; 1 1 2 3 1 1 4 1 4 1; 2 2 2 3 1 1 4 1 4 1; 1 1 1 1 1 1 1 1 1 1; 1 1 1 1 1 1 1 1 1 1; 1 1 1 1 1 1 1 1 1 1; 1 1 1 1 1 1 1 1 1 1]',Y,k);
            drenchbutton
            app.Label.Text = num2str(666);
            clear sound;
            [y,Fs] = audioread('anotherclick.mp3');
            sound(y,Fs);
        end

        % Button pushed function: Button, Button_2
        function ButtonPushed(app, event)
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)


                clear sound;
                [y,Fs] = audioread('gymno.mp3');
                sound(y,Fs);
               
        end

        % Callback function
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Close request function: DRENCHUIFigure
        function DRENCHUIFigureCloseRequest(app, event)
        
            delete(app)
            
             
            close all;
            clear sound;
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
              global x
              global k    
       close all     
       [x,k] = drenchgame();
       Y = randi(5);
       [x,k] = drenchgame(x,Y,k);
       drenchbutton
       app.Label.Text = num2str(0);
       
        end

        % Button pushed function: StopButton
        function StopButtonPushed2(app, event)
            clear sound;
        end

        % Button pushed function: HowtoplayButton
        function HowtoplayButtonPushed(app, event)
             message = "You start with the square in the top left corner - this is your area. You add to your area by linking neighbouring squares that are the same colour by clicking the 5 buttons at the bottom. The goal is to fill the whole board with the same colour in the smallest amount of turns possible. Once you have done this, click restart to try and beat your score! Also don't forget to look for the easter egg!" ;
             msgbox(message, "How to play");
             [y,Fs] = audioread('click.mp3');
                sound(y,Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DRENCHUIFigure = uifigure('Visible', 'off');
            app.DRENCHUIFigure.Name = 'DRENCH';
            app.DRENCHUIFigure.CloseRequestFcn = createCallbackFcn(app, @DRENCHUIFigureCloseRequest, true);
            app.Button = uibutton(app.DRENCHUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Button_2 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_22.Text = '';
            app.Button_23 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_23.Text = '';
            app.Button_24 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_24.Text = '';
            app.Button_25 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_25.Text = '';
            app.Button_26 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_26.Text = '';
            app.Button_27 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_28.Text = '';
            app.Button_29 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_29.Text = '';
            app.Button_30 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_32.Text = '';
            app.Button_33 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_33.Text = '';
            app.Button_34 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_34.Text = '';
            app.Button_35 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_35.Text = '';
            app.Button_36 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_36.Text = '';
            app.Button_37 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_37.Text = '';
            app.Button_38 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_38.Text = '';
            app.Button_39 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_39.Text = '';
            app.Button_40 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_40.Text = '';
            app.Button_41 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_41.Text = '';
            app.Button_42 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_42.Text = '';
            app.Button_43 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_43.Text = '';
            app.Button_44 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_44.Text = '';
            app.Button_45 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_45.Text = '';
            app.Button_46 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_46.Text = '';
            app.Button_47 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_47.Text = '';
            app.Button_48 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_48.Text = '';
            app.Button_49 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_49.Text = '';
            app.Button_50 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_50.Text = '';
            app.Button_51 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_51.Text = '';
            app.Button_52 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_52.Text = '';
            app.Button_53 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_53.Text = '';
            app.Button_54 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_54.Text = '';
            app.Button_55 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_55.Text = '';
            app.Button_56 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_56.Text = '';
            app.Button_57 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_57.Text = '';
            app.Button_58 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_58.Text = '';
            app.Button_59 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_59.Text = '';
            app.Button_60 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_60.Text = '';
            app.Button_61 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_61.Text = '';
            app.Button_62 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_62.Text = '';
            app.Button_63 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_63.Text = '';
            app.Button_64 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_64.Text = '';
            app.Button_65 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_65.Text = '';
            app.Button_66 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_66.Text = '';
            app.Button_67 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_67.Text = '';
            app.Button_68 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_68.Text = '';
            app.Button_69 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_69.Text = '';
            app.Button_70 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_70.Text = '';
            app.Button_71 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_71.Text = '';
            app.Button_72 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_72.Text = '';
            app.Button_73 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_73.Text = '';
            app.Button_74 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_74.Text = '';
            app.Button_75 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_75.Text = '';
            app.Button_76 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_76.Text = '';
            app.Button_77 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_77.Text = '';
            app.Button_78 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_78.Text = '';
            app.Button_79 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_79.Text = '';
            app.Button_80 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_80.Text = '';
            app.Button_81 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_81.Text = '';
            app.Button_82 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_82.Text = '';
            app.Button_83 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_83.Text = '';
            app.Button_84 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_84.Text = '';
            app.Button_85 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_85.Text = '';
            app.Button_86 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_86.Text = '';
            app.Button_87 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_87.Text = '';
            app.Button_88 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_88.ButtonPushedFcn = createCallbackFcn(app, @Button_88Pushed, true);
            app.Button_88.Text = '';
            app.Button_89 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_89.Text = '';
            app.Button_90 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_90.Text = '';
            app.Button_91 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_91.Text = '';
            app.Button_92 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_92.Text = '';
            app.Button_93 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_93.Text = '';
            app.Button_94 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_94.Text = '';
            app.Button_95 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_95.Text = '';
            app.Button_96 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_96.Text = '';
            app.Button_97 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_97.Text = '';
            app.Button_98 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_98.Text = '';
            app.Button_99 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_99.Text = '';
            app.Button_100 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_100.Text = '';
            app.Button_101 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_101.ButtonPushedFcn = createCallbackFcn(app, @Button_101Pushed, true);
            app.Button_101.Text = '';
            app.Button_102 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_102.ButtonPushedFcn = createCallbackFcn(app, @Button_102Pushed, true);
            app.Button_102.Text = '';
            app.Button_103 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_103.ButtonPushedFcn = createCallbackFcn(app, @Button_103Pushed, true);
            app.Button_103.Text = '';
            app.Button_104 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_104.ButtonPushedFcn = createCallbackFcn(app, @Button_104Pushed, true);
            app.Button_104.Text = '';
            app.Button_105 = uibutton(app.DRENCHUIFigure, 'push');
            app.Button_105.ButtonPushedFcn = createCallbackFcn(app, @Button_105Pushed, true);
            app.Button_105.Text = '';
            app.Label = uilabel(app.DRENCHUIFigure);
            app.Label.Text = '0';
            app.SCORELabel = uilabel(app.DRENCHUIFigure);
            app.SCORELabel.Text = 'SCORE';
            app.PlayButton = uibutton(app.DRENCHUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.MUSICLabel = uilabel(app.DRENCHUIFigure);
            app.MUSICLabel.Text = 'MUSIC';
            app.RestartButton = uibutton(app.DRENCHUIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.StopButton = uibutton(app.DRENCHUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed2, true);
            app.StopButton.Text = 'Stop';
            app.Label_2 = uilabel(app.DRENCHUIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.DRENCHUIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.DRENCHUIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.DRENCHUIFigure);
            app.Label_5.Text = '';
            app.HowtoplayButton = uibutton(app.DRENCHUIFigure, 'push');
            app.HowtoplayButton.ButtonPushedFcn = createCallbackFcn(app, @HowtoplayButtonPushed, true);
            app.HowtoplayButton.Text = 'How to play';
            % Show the figure after all components are created
            app.DRENCHUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drench

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DRENCHUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DRENCHUIFigure)
        end
    end
end